classdef LS_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ExposureTimemsEditField        matlab.ui.control.NumericEditField
        ExposureTimemsEditFieldLabel   matlab.ui.control.Label
        CurrentPosButton_2             matlab.ui.control.Button
        CurrentPosButton               matlab.ui.control.Button
        CameraSettingsLabel            matlab.ui.control.Label
        StopPositionEditField          matlab.ui.control.NumericEditField
        StopPositionEditFieldLabel     matlab.ui.control.Label
        AcquireSettingsLabel           matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        AdjustSmallEditField           matlab.ui.control.NumericEditField
        AdjustSmallEditFieldLabel      matlab.ui.control.Label
        small_increase                 matlab.ui.control.Button
        small_decrease                 matlab.ui.control.Button
        StartButton_2                  matlab.ui.control.Button
        AdjustLargeEditField           matlab.ui.control.NumericEditField
        AdjustLargeEditFieldLabel      matlab.ui.control.Label
        SetPositionEditField           matlab.ui.control.NumericEditField
        SetPositionEditFieldLabel      matlab.ui.control.Label
        CurrentPositionEditField       matlab.ui.control.NumericEditField
        CurrentPositionEditFieldLabel  matlab.ui.control.Label
        StatusLamp                     matlab.ui.control.Lamp
        StatusLampLabel                matlab.ui.control.Label
        large_increase                 matlab.ui.control.Button
        large_decrease                 matlab.ui.control.Button
        StartCameraButton              matlab.ui.control.Button
        HomeButton                     matlab.ui.control.Button
        StartPositionEditField         matlab.ui.control.NumericEditField
        StartPositionEditFieldLabel    matlab.ui.control.Label
        AcquireButton                  matlab.ui.control.Button
        NumberofImagesEditField        matlab.ui.control.NumericEditField
        NumberofImagesEditFieldLabel   matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        op_states = 1;
        ConexCC;
        current_position;
        move_value;
        cameraObject;
        imageObject;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.ConexCC = serialport("COM10",921600);
%             configureTerminator(app.ConexCC,"CR/LF");
            app.cameraObject = webcam("Microsoft® LifeCam HD-3000");
            app.imageObject = image(app.UIAxes);
            axis(app.UIAxes,'ij');
            res = split(app.webcamObject.Resolution,'x');
            app.UIAxes.XLim = [0,str2double(res{1})];
            app.UIAxes.YLim = [0,str2double(res{2})];
            app.webcamObject.preview(app.imageObject);
        end

        % Button pushed function: AcquireButton
        function AcquireRun(app, event)
            run_acquire(app.ConexCC, app.StartPositionEditField.Value, app.StopPositionEditField.Value ,app.NumberofImagesEditField.Value);
        end

        % Button pushed function: HomeButton
        function Call_Home(app, event)
            app.op_states = Homing(app.op_states, app.ConexCC);
        end

        % Button pushed function: StartCameraButton
        function move_stage(app, event)
            
        end

        % Callback function
        function update_move_value(app, event)
            app.move_value = app.MovemmSlider.Value;
        end

        % Button pushed function: StartButton_2
        function set_position(app, event)
            absolute_move(app.SetPositionEditField.Value, app.ConexCC);
            app.current_position = app.SetPositionEditField.Value;
        end

        % Value changed function: CurrentPositionEditField
        function update_current_position(app, event)
            app.current_position = app.CurrentPositionEditField.Value;
        end

        % Button pushed function: small_increase
        function small_increment(app, event)
            relative_move(app.AdjustSmallEditField.Value, app.ConexCC);
            app.current_position = app.current_position + app.AdjustSmallEditField.Value;
        end

        % Button pushed function: small_decrease
        function small_decrement(app, event)
            relative_move(-app.AdjustSmallEditField.Value, app.ConexCC);
            app.current_position = app.current_position - app.AdjustSmallEditField.Value;
        end

        % Button pushed function: large_increase
        function large_increment(app, event)
            relative_move(app.AdjustLargeEditField.Value, app.ConexCC);
            app.current_position = app.current_position + app.AdjustLargeEditField.Value;
        end

        % Button pushed function: large_decrease
        function large_decrement(app, event)
            relative_move(app.AdjustLargeEditField.Value, app.ConexCC);
            app.current_position = app.current_position - app.AdjustLargeEditField.Value;
        end

        % Button pushed function: CurrentPosButton
        function set_start_currentpos(app, event)
            app.StartPositionEditField.Value = app.current_position;
        end

        % Button pushed function: CurrentPosButton_2
        function set_stop_currentpos(app, event)
            app.StopPositionEditField.Value = app.current_position;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NumberofImagesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofImagesEditFieldLabel.Text = 'Number of Images';
            app.NumberofImagesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofImagesEditField.Value = 10;
            app.AcquireButton = uibutton(app.UIFigure, 'push');
            app.AcquireButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireRun, true);
            app.AcquireButton.Text = 'Acquire';
            app.StartPositionEditFieldLabel = uilabel(app.UIFigure);
            app.StartPositionEditFieldLabel.Text = 'Start Position';
            app.StartPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartPositionEditField.Value = 1;
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @Call_Home, true);
            app.HomeButton.Text = {'Home'; ''};
            app.StartCameraButton = uibutton(app.UIFigure, 'push');
            app.StartCameraButton.ButtonPushedFcn = createCallbackFcn(app, @move_stage, true);
            app.StartCameraButton.Text = 'Start';
            app.large_decrease = uibutton(app.UIFigure, 'push');
            app.large_decrease.ButtonPushedFcn = createCallbackFcn(app, @large_decrement, true);
            app.large_decrease.Text = '-';
            app.large_increase = uibutton(app.UIFigure, 'push');
            app.large_increase.ButtonPushedFcn = createCallbackFcn(app, @large_increment, true);
            app.large_increase.Text = '+';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.CurrentPositionEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentPositionEditFieldLabel.Text = 'Current Position';
            app.CurrentPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentPositionEditField.ValueChangedFcn = createCallbackFcn(app, @update_current_position, true);
            app.SetPositionEditFieldLabel = uilabel(app.UIFigure);
            app.SetPositionEditFieldLabel.Text = 'Set Position';
            app.SetPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdjustLargeEditFieldLabel = uilabel(app.UIFigure);
            app.AdjustLargeEditFieldLabel.Text = 'Adjust (Large)';
            app.AdjustLargeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdjustLargeEditField.Value = 0.5;
            app.StartButton_2 = uibutton(app.UIFigure, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @set_position, true);
            app.StartButton_2.Text = 'Start';
            app.small_decrease = uibutton(app.UIFigure, 'push');
            app.small_decrease.ButtonPushedFcn = createCallbackFcn(app, @small_decrement, true);
            app.small_decrease.Text = '-';
            app.small_increase = uibutton(app.UIFigure, 'push');
            app.small_increase.ButtonPushedFcn = createCallbackFcn(app, @small_increment, true);
            app.small_increase.Text = '+';
            app.AdjustSmallEditFieldLabel = uilabel(app.UIFigure);
            app.AdjustSmallEditFieldLabel.Text = 'Adjust (Small)';
            app.AdjustSmallEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdjustSmallEditField.Value = 0.1;
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = 'Stop';
            app.AcquireSettingsLabel = uilabel(app.UIFigure);
            app.AcquireSettingsLabel.Text = 'Acquire Settings';
            app.StopPositionEditFieldLabel = uilabel(app.UIFigure);
            app.StopPositionEditFieldLabel.Text = 'Stop Position';
            app.StopPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.StopPositionEditField.Value = 1;
            app.CameraSettingsLabel = uilabel(app.UIFigure);
            app.CameraSettingsLabel.Text = 'Camera Settings';
            app.CurrentPosButton = uibutton(app.UIFigure, 'push');
            app.CurrentPosButton.ButtonPushedFcn = createCallbackFcn(app, @set_start_currentpos, true);
            app.CurrentPosButton.Text = 'Current Pos.';
            app.CurrentPosButton_2 = uibutton(app.UIFigure, 'push');
            app.CurrentPosButton_2.ButtonPushedFcn = createCallbackFcn(app, @set_stop_currentpos, true);
            app.CurrentPosButton_2.Text = 'Current Pos.';
            app.ExposureTimemsEditFieldLabel = uilabel(app.UIFigure);
            app.ExposureTimemsEditFieldLabel.Text = 'Exposure Time (ms)';
            app.ExposureTimemsEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LS_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end